classdef Savic_Project2021 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SinkingintoTheMariannaTrenchLabel  matlab.ui.control.Label
        MasskgEditFieldLabel        matlab.ui.control.Label
        MasskgEditField             matlab.ui.control.NumericEditField
        CalculateButton             matlab.ui.control.Button
        MaterialDropDownLabel       matlab.ui.control.Label
        MaterialDropDown            matlab.ui.control.DropDown
        WaterPressureMPaLabel       matlab.ui.control.Label
        WaterPressureMPaGauge       matlab.ui.control.Gauge
        InitialPositionmLabel       matlab.ui.control.Label
        InitialPositionmEditField   matlab.ui.control.NumericEditField
        InitialVelocitymsLabel      matlab.ui.control.Label
        InitialVelocitymsEditField  matlab.ui.control.NumericEditField
        ByAlexSavicLabel            matlab.ui.control.Label
        UIAxesDepth                 matlab.ui.control.UIAxes
        UIAxesAcc_Vel               matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_Forces               matlab.ui.control.UIAxes
    end


    methods (Static)
        
        function [detect,stopint, direction] = endevent(t, y, varargin)
            %{
            function [detect,stopint, direction] = endevent(t, y, varargin)
            Defines the stopping point of integration for the sinking
            sphere
            Inputs:
                t           = time vector
                y           = vector contaning current elevation (m) and velocity (m/s) of sphere
                varargin    = the extra variable arguments required for the
                              function the endevent vector is used for
            Outputs:
                detect      = vector containing the stoppping point of
                              integration
                stopint     = vector that defines what to do when stopping
                              point is reached
                direction   = vector containing the directions associated
                              with the stopping opint
            %}
            detect = y(1) + 11032;  %Detect height = -11032m
            stopint = 1;    %Stop integration
            direction = 0;  %Direction does not matter
            
        end
        
        function dydt = ode_Fun(t, y, Cd, m, density, bulk_mod)
            %{
            function dydt = ode_Fun(t, y, Cd, m, density, bulk_mod)
            Contains the system of differential equations for a sinking sphere into the
            Marianna Trench
            Inputs:
                t           = time vector
                y           = vector contaning current elevation (m) and velocity (m/s) of sphere
                Cd          = drag coefficient
                m           = mass of sphere
                density     = desnity of the material sphere is made from
                bulk_mod    = bulk modulus of the material
            Outputs:
                dvdt        = vector containing the velocity and accelration of the sphere
            %}
            
            
            %y(1) = height (m)
            %y(2) = velocity (m/s)
            
            %gravity (divide by 1000 because y(1) is in m and we want km)
            g = 9.806412 - 0.00403039734 * (y(1) / 1000);   %m/s^2
            
            if y(1) > 0 %Above water
                
                %Known vlaues of z(elevation) and p(air density)
                zknown = [-1, 0, 1, 2, 3, 4, 5, 6, 7, 8, 9, 10, 15, 20, 25, 30, 40, 50, 60, 70, 80];    %km
                pknown = [1.347, 1.225, 1.112, 1.007, 0.9093, 0.8194, 0.7364, 0.6601, 0.5900, 0.5258, 0.4671, 0.4135, 0.1948, 0.08891, 0.04008, 0.01841, 0.003996, 0.001027, 0.0003097, 8.283*10^-5, 1.846*10^-5]; %kg/m^3
                
                %Gets the interpolated p (density of air) values
                p = interp1(zknown, pknown, y(1)/1000);  %kg/m^3
                
                %Volume from mass and density
                V = m/density;  %kg^3
                
                %Cross Sectional Area
                %V = 4/3 * pi * r^2
                r = (V/((4/3)*pi))^(1/3);   %m
                A = r^2 * pi;   %m^2
                
                %Forces (gravity and drag)
                Fg = -m*g;
                Fd = 0.5*p*A*Cd*(y(2)^2);
                if y(2) > 0     %Makes sure drag opposes motion
                    Fd = -Fd;
                end
                
                dydt = [y(2); (Fd + Fg) / m];
                
                
            else        %In water
                
                %Known values of z(elevation) and p(water density)
                zknown = [0, -1000, -2000, -4000, -6000, -8000, -10000, -11034];    %m
                pknown = [1028.13, 1032.85, 1037.47, 1046.40, 1054.95, 1063.15, 1071.04, 1079.53]; %kg/m^3
                
                %Gets the interpolated p (density of water) values
                p = interp1(zknown, pknown, y(1));  %kg/m^3
                
                %Pressure of the water
                Pressure = -y(1)*p*g;   %Pa or kg/m*s^2
                
                %Volume from mass and density
                V = m/density;  %kg^3
                
                %Volume after water pressure and Bulk Modulus (Bulk Modus is Pa * 10^9)
                V = V - (V/(bulk_mod * 10^9)) * Pressure;   %m^3
                
                %Cross Sectional Area
                %V = 4/3 * pi * r^2
                r = (V/((4/3)*pi))^(1/3);   %m
                A = r^2 * pi;   %m^2
                
                %Forces (gravity, bouyancy and drag)
                Fg = -m*g;
                Fb = p*g*V;
                Fd = 0.5*p*A*(Cd)*((y(2)^2));
                if y(2) > 0     %makes sure drag opposes motion
                    Fd = -Fd;
                end
                
                dydt = [y(2); (Fb + Fd + Fg) / m];
                
            end
        end
        
        function [radius, Pressure, Fg, Fb, Fd] = Sinking_Ball(y, Cd, m, density, bulk_mod)
            
            %{
            function [radius, Pressure, Fg, Fb, Fd] = Sinking_Ball(y, Cd, m, density, bulk_mod)
            Calculates the radius of the sphere, water pressurem, and
            forces that the sphere experiences at different elevations and
            velocities
            Inputs:
                y           = vector contaning current elevation (m) and velocity (m/s) of sphere
                Cd          = drag coefficient
                m           = mass of sphere
                density     = desnity of the material sphere is made from
                bulk_mod    = bulk modulus of the material
            Outputs:
                radius      = radius of sphere at current elevation (note:
                              dependent on Pressure
                Pressure    = water pressure at current elevation
                Fg          = force of gravity
                Fb          = force of buoyancy
                Fd          = force of drag
            %}
            
            %y(1) = height (m)
            %y(2) = velocity (m/s)
            
            %gravity (divide by 1000 because y(1) is in m and we want km)
            g = 9.806412 - 0.00403039734 * (y(:, 1) / 1000);   %m/s^2
            
            %Preallocate vectors
            radius = zeros(length(y), 1);
            Pressure = zeros(length(y), 1);
            Fb = zeros(length(y), 1);
            Fd = zeros(length(y), 1);
            
            %Force of gravity
            Fg = -m*g;
            
            %Loop through all y values
            for i = 1:length(y)
                if y(i, 1) > 0  %If elevation above water
                    
                    %Known vlaues of z(elevation) and p(air density)
                    zknown = [-1, 0, 1, 2, 3, 4, 5, 6, 7, 8, 9, 10, 15, 20, 25, 30, 40, 50, 60, 70, 80];    %km
                    pknown = [1.347, 1.225, 1.112, 1.007, 0.9093, 0.8194, 0.7364, 0.6601, 0.5900, 0.5258, 0.4671, 0.4135, 0.1948, 0.08891, 0.04008, 0.01841, 0.003996, 0.001027, 0.0003097, 8.283*10^-5, 1.846*10^-5]; %kg/m^3
                    
                    %Gets the interpolated p (density of air) values
                    p = interp1(zknown, pknown, y(i, 1)/1000);  %kg/m^3
                    
                    %Volume from mass and density
                    V = m/density;  %kg^3
                    
                    %Cross Sectional Area
                    %V = 4/3 * pi * r^2
                    radius(i) = (V/((4/3)*pi))^(1/3);   %m
                    A = radius(i)^2 * pi;   %m^2
                    
                    %Forces (drag and buoyant)
                    Fd(i) = 0.5*p*A*Cd*(y(i, 2)^2);
                    if y(i, 2) > 0     %Makes sure drag opposes direction of motion
                        Fd = -Fd;
                    end
                    Fb(i) = 0; %Bouyancy = 0 because above water
                    
                    %Water pressure
                    Pressure(i) = 0;    %Pressure = 0 because above water
                    
                else    %If elevation below water
                    
                    %Known values of z(depth) and p(water density)
                    zknown = [0, -1000, -2000, -4000, -6000, -8000, -10000, -11034];    %m
                    pknown = [1028.13, 1032.85, 1037.47, 1046.40, 1054.95, 1063.15, 1071.04, 1079.53]; %kg/m^3
                    
                    %Gets the interpolated p (density of water) values
                    p = interp1(zknown, pknown, y(i, 1));  %kg/m^3
                    
                    %Pressure of the water
                    Pressure(i) = -y(i, 1)*p*g(i);   %Pa or kg/m*s^2
                    
                    %Volume from mass and density
                    V = m/density;  %kg^3
                    
                    %Volume after water pressure and Bulk Modulus (Bulk Modus is Pa * 10^9)
                    V = V - (V/(bulk_mod * 10^9)) * Pressure(i);   %m^3
                    
                    %Cross Sectional Area
                    %V = 4/3 * pi * r^2
                    radius(i) = (V/((4/3)*pi))^(1/3);   %m
                    A = radius(i)^2 * pi;   %m^2
                    
                    %Forces (drag and bouyant)
                    Fd(i) = 0.5*p*A*Cd*(y(i, 2)^2);
                    if y(i, 2) > 0     %Makes sure drag opposes direction of motion
                        Fd(i) = -Fd(i);
                    end
                    Fb(i) = p*g(i)*V;
                    
                end
            end
            
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %Initializng variables
            m = app.MasskgEditField.Value;
            
            density = strsplit(app.MaterialDropDown.Value);
            density = density(1,1);
            density = str2num(cell2mat(density));
            
            bulk_mod = strsplit(app.MaterialDropDown.Value);
            bulk_mod = bulk_mod(1,2);
            bulk_mod = str2num(cell2mat(bulk_mod));
            
            Cd = 0.47;
            
            %Defining tspan and initial criteria
            tspan = [0, inf];
            x0 = app.InitialPositionmEditField.Value;
            v0 = app.InitialVelocitymsEditField.Value;
            initial = [x0, v0];
            
            %End criteria
            opts = odeset('Events', @app.endevent);
            
            %Solves for t and y
            [t, y, te, ye] = ode15s(@app.ode_Fun, tspan, initial, opts, Cd, m, density, bulk_mod);
            
            %Calcualates acceleration using forward differentiation
            acc = diff(y(:,2)) ./ diff(t);  %Acceleration values
            n = length(t);
            tm = (t(1:n-1) + t(2:n)) ./2;   %Acceleratoin times
            
            %Calculates radius, pressure and forces
            [r, Pressure, Fg, Fb, Fd] = app.Sinking_Ball(y, Cd, m, density, bulk_mod);
            
            %Clears the axis
            cla(app.UIAxes);
            cla(app.UIAxesDepth);
            cla(app.UIAxesAcc_Vel);
            cla(app.UIAxes_Forces);
            
            %Sets up Gauge limits
            app.WaterPressureMPaGauge.Limits = [0, 120];
            
            %Animated lines
            pos_Animated = animatedline(app.UIAxesDepth, 'color', 'b', 'LineWidth', 2);
            vel_Animated = animatedline(app.UIAxesAcc_Vel, 'color', 'g', 'LineWidth', 2);
            acc_Animated = animatedline(app.UIAxesAcc_Vel, 'color', 'r', "LineWidth", 2);
            
            Fg_Animated = animatedline(app.UIAxes_Forces, 'color', 'r', 'LineWidth', 2);
            Fb_Animated = animatedline(app.UIAxes_Forces, 'color', 'g', 'LineWidth', 2);
            Fd_Animated = animatedline(app.UIAxes_Forces, 'color', 'b', 'LineWidth', 2);
            
            %Plots the animated graphs and figures
            for i = 1: length(t)
                %Add points to Pos, vel & acc
                addpoints(pos_Animated, t(i), y(i, 1))
                addpoints(vel_Animated, t(i), y(i, 2))
                if(i < length(tm))  %tm is 1 element shorter than t
                    addpoints(acc_Animated, tm(i), acc(i));
                end
                
                %Add points to Forces
                addpoints(Fg_Animated, t(i), Fg(i));
                addpoints(Fb_Animated, t(i), Fb(i));
                addpoints(Fd_Animated, t(i), Fd(i));
                
                %Creates legend and draws
                legend(app.UIAxesAcc_Vel, 'Velocity', 'Acceleration', 'location', 'best')
                legend(app.UIAxes_Forces, 'Gravity', 'Buoyancy', 'Drag', 'location', 'best')
                drawnow
                
                %Plots  cross sectional area of sphere
                pos = [-r(i), -r(i), 2*r(i), 2*r(i)];
                rectangle(app.UIAxes, 'position', pos, 'Curvature', [1, 1]);
                app.UIAxes.XLim = [-r(1), r(1)];
                app.UIAxes.YLim = [-r(1), r(1)];
                
                %Plots pressure
                app.WaterPressureMPaGauge.Value = Pressure(i) * 10^-6;
            end
            
            
            %Plots data once again so it can be inspected and clicked on
            %on the graph
            plot(app.UIAxesDepth, t, y(:, 1), 'b-', "LineWidth", 2);
            plot(app.UIAxesAcc_Vel, t, y(:, 2), 'm-', tm, acc, 'r-', "LineWidth", 2);
            legend(app.UIAxesAcc_Vel, 'Velocity', 'Acceleration', 'location', 'best')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SinkingintoTheMariannaTrenchLabel = uilabel(app.UIFigure);
            app.SinkingintoTheMariannaTrenchLabel.Text = 'Sinking into The Marianna Trench';
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditField.Value = 10;
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaterialDropDownLabel = uilabel(app.UIFigure);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.UIFigure);
            app.MaterialDropDown.Items = {'Steel', 'PVC', 'Copper', 'Silicate Glass', 'Aluminum'};
            app.MaterialDropDown.ItemsData = {'7840 160', '1420 4.7', '8930 140', '2203 36.7', '2570 62'};
            app.MaterialDropDown.Value = '7840 160';
            app.WaterPressureMPaLabel = uilabel(app.UIFigure);
            app.WaterPressureMPaLabel.Text = {'Water Pressure '; '(MPa)'};
            app.WaterPressureMPaGauge = uigauge(app.UIFigure, 'circular');
            app.InitialPositionmLabel = uilabel(app.UIFigure);
            app.InitialPositionmLabel.Text = {'Initial Position '; '(m)'};
            app.InitialPositionmEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialVelocitymsLabel = uilabel(app.UIFigure);
            app.InitialVelocitymsLabel.Text = {'Initial Velocity '; '(m/s)'};
            app.InitialVelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ByAlexSavicLabel = uilabel(app.UIFigure);
            app.ByAlexSavicLabel.Text = 'By: Alex Savic';
            app.UIAxesDepth = uiaxes(app.UIFigure);
            title(app.UIAxesDepth, 'Depth of Sphere')
            xlabel(app.UIAxesDepth, 'Time (s)')
            ylabel(app.UIAxesDepth, 'Elevation (m)')
            zlabel(app.UIAxesDepth, 'Z')
            app.UIAxesAcc_Vel = uiaxes(app.UIFigure);
            title(app.UIAxesAcc_Vel, 'Acceleration and Velocity of Sphere')
            xlabel(app.UIAxesAcc_Vel, 'Time (s)')
            ylabel(app.UIAxesAcc_Vel, 'Acceleration (m/s^2), Velocity(m/s)')
            zlabel(app.UIAxesAcc_Vel, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cross Sectional Area')
            xlabel(app.UIAxes, 'Radius (m)')
            ylabel(app.UIAxes, 'Radius (m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_Forces = uiaxes(app.UIFigure);
            title(app.UIAxes_Forces, 'Forces')
            xlabel(app.UIAxes_Forces, 'Time (s)')
            ylabel(app.UIAxes_Forces, 'Force (N)')
            zlabel(app.UIAxes_Forces, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Savic_Project2021

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end